classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GeneradordearboldepatronesfrecuentesLabel  matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        UmbralLabel          matlab.ui.control.Label
        OSpinner             matlab.ui.control.Spinner
        Ejemplo2Button       matlab.ui.control.Button
        Ejemplo1Button       matlab.ui.control.Button
        lblConsole           matlab.ui.control.Label
        LimpiartodoButton    matlab.ui.control.Button
        LimpiarmatrizButton  matlab.ui.control.Button
        GenerarButton        matlab.ui.control.Button
        AgregarButton        matlab.ui.control.Button
        GridLayout2_2        matlab.ui.container.GridLayout
        xButton              matlab.ui.control.StateButton
        wButton              matlab.ui.control.StateButton
        vButton              matlab.ui.control.StateButton
        uButton              matlab.ui.control.StateButton
        tButton              matlab.ui.control.StateButton
        sButton              matlab.ui.control.StateButton
        rButton              matlab.ui.control.StateButton
        qButton              matlab.ui.control.StateButton
        pButton              matlab.ui.control.StateButton
        oButton              matlab.ui.control.StateButton
        Button               matlab.ui.control.StateButton
        nButton              matlab.ui.control.StateButton
        mButton              matlab.ui.control.StateButton
        lButton              matlab.ui.control.StateButton
        kButton              matlab.ui.control.StateButton
        jButton              matlab.ui.control.StateButton
        iButton              matlab.ui.control.StateButton
        hButton              matlab.ui.control.StateButton
        gButton              matlab.ui.control.StateButton
        fButton              matlab.ui.control.StateButton
        eButton              matlab.ui.control.StateButton
        dButton              matlab.ui.control.StateButton
        cButton              matlab.ui.control.StateButton
        bButton              matlab.ui.control.StateButton
        aButton              matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        matL = [];
        dataUsed = false;
    end
    
    methods (Access = public)
        
        function showThree = func(app, opc, t)
            if opc == 1
                [n,t,r] = xlsread('data.xlsx');
            elseif opc == 2
                [n,t,r] = xlsread('data02.xlsx');
            end
                
            umbral = app.OSpinner.Value;
            
            aux = t.';
            nWords = 8;
            mat = strcat('a':'n','ñ','o':'x');
            transTbl = strings(nWords,25);
            
            iN = 1;
            jN = 0;
            for i=1 : numel(aux)
                jN= jN+1;
                transTbl(iN,jN) = aux{i};
                
                if mod(i,25)==0
                    iN= iN+1;
                    jN= 0;
                end
            end
            
            incidencias = zeros(1,25);
            for i=1: nWords
                for j=1: 25
                    if find(transTbl(i:i,1:25) == mat(j))
                        incidencias(j) = incidencias(j)+1;
                    end
                end
            end
            
            
            newTransTbls = [ ];
            for i=1: nWords
                strAx = '';
                for j=1: 25
                    if mat(j)==transTbl(i,j) && incidencias(j)>umbral
                        strAx = app.func2(mat, incidencias, strAx, j);
                    end
                end
                newTransTbls = [newTransTbls strcat(strAx, "")];
            end
            
            justPoint = [ ];
            points = [ ];
            nodes = [ ];
            nodesCh = [ ];
            
            newTransTbls = newTransTbls.';
            
            x = 1;
            y = 1;
            newNodes = 0;
            for i=1:nWords
                B = convertStringsToChars( newTransTbls(i) );
                y=0;
                
                for j=1:numel(B)-1
                    nP = strcat(B(j),",",B(j+1));
                    nC = strcat(num2str(x),",",num2str(y));
                    nCnext = strcat(num2str(x),",",num2str(y));
                    
                    if numel(justPoint) == 0
                        justPoint = [ justPoint strcat("",nP) ];
                        points = [ points strcat("",nP)  ];
                        
                        nodes = [ strcat("",B(j)) ];
                        nodes = [ nodes strcat("",B(j+1)) ];
                        nodesCh = [ strcat(num2str(x),",",num2str(y)) ];
                        nodesCh = [ nodesCh strcat(num2str(x),",",num2str(y-1)) ];
                    else
                        if i==1
                            justPoint = [ justPoint strcat("",nP) ];
                            points = [ points strcat("",nP) ];
                            nodes = [ nodes strcat("",B(j+1))];
                            nodesCh = [ nodesCh strcat(num2str(x),",",num2str(y-1)) ];
                        else
                            if numel( find( justPoint==nP ) ) == 0 
                                newNodes = newNodes+1;
                                axCh = '';
                                for a=1:newNodes
                                    axCh = strcat(axCh, "'");
                                end
                                
                                points = [ points strcat(B(j),",",B(j),axCh) ];
                                nodes = [ nodes strcat("",B(j),axCh)];
                                nodesCh = [ nodesCh strcat(num2str(x),",",num2str(y)) ];
                                
                                for k=j: numel(B)-1
                                   nP = strcat(B(k),",",B(k+1));
                                   nC = strcat(num2str(x),",",num2str(y));
                    
                                   justPoint = [ justPoint strcat("",nP) ];
                                   points = [ points strcat(B(k),axCh,",",B(k+1),axCh) ];
                                   nodes = [ nodes strcat("",B(k+1),axCh)];
                                   nodesCh = [ nodesCh strcat(num2str(x-1),",",num2str(y)) ];
                                   x = x-1;
                               end
                               j=k;
                            end
                        end
                    end
                    y = y-1;
                end
            end
            
            G = zeros(numel(nodes), numel(nodes));
            V = zeros(2, numel(nodes));
            for i=1: numel(nodes)
                for j=1: numel(nodes)
                    vertic = strcat(nodes(i),",",nodes(j));
                    if find( points==vertic ) ~= 0
                        G(i,j) = 1;
                    end
                end
                [dot ,y] = split(nodesCh(i), ",");
                V(1,i) = str2double(dot(1));
                V(2,i) = str2double(dot(2));
            end
            
            V = V.';
            figure(100);
            plot( V(:,1), V(:,2), 'ok','MarkerSize',10 );
            hold on
            
            for i=1: size(G,1)
                for j=1: size(G,1)
                    if G(i,j)==1
                        [dot01 ,~] = split( nodesCh(find( nodes==nodes(i) )), ",");
                        [dot02 ,~] = split( nodesCh(find( nodes==nodes(j) )), ",");
                        arisA = [str2double(dot01(1)) str2double(dot01(2))];
                        arisB = [str2double(dot02(1))  str2double(dot02(2))];
                        
                        Y = [ arisA(1:2); arisB(1:2) ];
                        
                        plot( Y(:,1), Y(:,2) ,'-r' );
                        hold on
                    end
                end
            end
            
            plot( [1,1], [1,1], 'ok','MarkerSize',15 );
            plot( [1,1], [0,1] ,'-r' );
            hold on
            
            app.lblConsole.Text = "Árbol generado";
        end
        
        function strAx = func2(app, mat, incidencias, strAx, j)
            if numel(strAx) == 0
                strAx = mat(j);
            elseif numel(strAx) == 1
                if incidencias(find(mat==mat(j))) >= incidencias(find(mat==strAx)) && j < find(mat==strAx)
                    strAx = strcat(mat(j),strAx);
                else
                    strAx = strcat(strAx,mat(j));
                end
            else
                if incidencias(j) >= incidencias(find(mat==strAx(1))) && j < find(mat==strAx(1))
                    strAx = strcat(mat(j),strAx);
                elseif incidencias(j) <= incidencias(find(mat==strAx(numel(strAx)))) && j > find(mat==strAx(numel(strAx)))
                    strAx = strcat(strAx,mat(j));
                else
                    inserted=0;
                    for k=2: numel(strAx)
                        if incidencias(j) == incidencias(find(mat==strAx(k))) && j < find(mat==strAx(k))
                            strAx = strcat(strAx(1:k), mat(j), strAx(k+1:numel(strAx)));
                            inserted=1;
                            break
                        else
                            if incidencias(j) > incidencias(find(mat==strAx(k))) 
                                strAx = strcat(strAx(1:k-1), mat(j), strAx(k:numel(strAx)));
                                inserted=1;
                                break
                            end
                        end
                    end
                    if inserted==0
                        strAx = strcat(strAx,mat(j));
                    end
                end
            end
            
            
        end
        
        function results = clearBtns(app)
            app.aButton.Value = false;
            app.bButton.Value = false;
            app.cButton.Value = false;
            app.dButton.Value = false;
            app.eButton.Value = false;
            
            app.fButton.Value = false;
            app.gButton.Value = false;
            app.hButton.Value = false;
            app.iButton.Value = false;
            app.jButton.Value = false;
            
            app.kButton.Value = false;
            app.lButton.Value = false;
            app.mButton.Value = false;
            app.nButton.Value = false;
            app.Button.Value = false;
            
            app.oButton.Value = false;
            app.pButton.Value = false;
            app.qButton.Value = false;
            app.rButton.Value = false;
            app.sButton.Value = false;
            
            app.tButton.Value = false;
            app.uButton.Value = false;
            app.vButton.Value = false;
            app.wButton.Value = false;
            app.xButton.Value = false;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            if app.dataUsed
                fig = uifigure;
                selection = uiconfirm(fig,'Desea continuar?','Tiene información guardada','Icon','warning', 'Options',{'Si','No'});
                
                switch selection
                    case 'Si'
                        app.dataUsed=false;
                    case 'No'
                        return
                end
            end
            
            matAux = strings(25, 1);
            
            if app.aButton.Value
                matAux(1) = 'a';
            else
                matAux(1) = '*';
            end
            
            if app.bButton.Value
                matAux(2) = 'b';
            else
                matAux(2) = '*';
            end
            
            if app.cButton.Value
                matAux(3) = 'c';
            else
                matAux(3) = '*';
            end
            
            if app.dButton.Value
                matAux(4) = 'd';
            else
                matAux(4) = '*';
            end
            
            if app.eButton.Value
                matAux(5) = 'e';
            else
                matAux(5) = '*';
            end
            
            if app.fButton.Value
                matAux(6) = 'f';
            else
                matAux(6) = '*';
            end
            
            if app.gButton.Value
                matAux(7) = 'g';
            else
                matAux(7) = '*';
            end
            
            if app.hButton.Value
                matAux(8) = 'h';
            else
                matAux(8) = '*';
            end
            
            if app.iButton.Value
                matAux(9) = 'i';
            else
                matAux(9) = '*';
            end
            
            if app.jButton.Value
                matAux(10) = 'j';
            else
                matAux(10) = '*';
            end
            
            if app.kButton.Value
                matAux(11) = 'k';
            else
                matAux(11) = '*';
            end
            
            if app.lButton.Value
                matAux(12) = 'l';
            else
                matAux(12) = '*';
            end
            
            if app.mButton.Value
                matAux(13) = 'm';
            else
                matAux(13) = '*';
            end
            
            if app.nButton.Value
                matAux(14) = 'n';
            else
                matAux(14) = '*';
            end
            
            if app.Button.Value
                matAux(15) = 'ñ';
            else
                matAux(15) = '*';
            end
            
            if app.oButton.Value
                matAux(16) = 'o';
            else
                matAux(16) = '*';
            end
            
            if app.pButton.Value
                matAux(17) = 'p';
            else
                matAux(17) = '*';
            end
            
            if app.qButton.Value
                matAux(18) = 'q';
            else
                matAux(18) = '*';
            end
            
            if app.rButton.Value
                matAux(19) = 'r';
            else
                matAux(19) = '*';
            end
            
            if app.sButton.Value
                matAux(20) = 's';
            else
                matAux(20) = '*';
            end
            
            if app.tButton.Value
                matAux(21) = 't';
            else
                matAux(21) = '*';
            end
            
            if app.uButton.Value
                matAux(22) = 'u';
            else
                matAux(22) = '*';
            end
            
            if app.vButton.Value
                matAux(23) = 'v';
            else
                matAux(23) = '*';
            end
            
            if app.wButton.Value
                matAux(24) = 'w';
            else
                matAux(24) = '*';
            end
            
            if app.xButton.Value
                matAux(25) = 'x';
            else
                matAux(25) = '*';
            end
            
            matAux = matAux.';
            if numel(app.matL) == 0
                app.matL = [ matAux ];
            else
                app.matL = [ app.matL ; matAux];
            end
            
            app.lblConsole.Text = "Agregado correctamente!";
            app.clearBtns();
            
        end

        % Button pushed function: Ejemplo1Button
        function Ejemplo1ButtonPushed(app, event)
            app.func(1, []);
        end

        % Button pushed function: Ejemplo2Button
        function Ejemplo2ButtonPushed(app, event)
            app.func(2, []);
        end

        % Button pushed function: LimpiarmatrizButton
        function LimpiarmatrizButtonPushed(app, event)
            app.clearBtns();
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            if app.dataUsed
                fig = uifigure;
                selection = uiconfirm(fig,'Desea continuar?','Tiene información guardada','Icon','warning', 'Options',{'Si','No'});
                
                if selection=='No'
                    return
                end
            end
            
            if numel(app.matL) == 0
                app.lblConsole.Text = 'Agregue una matriz primero!!';
            else
                app.dataUsed = true;
                app.lblConsole.Text = "Empezando programa con %d matrices y %d de umbral",numel(app.matL),app.OSpinner.Value;
                
                app.func(-1, app.matL);
            end
        end

        % Button pushed function: LimpiartodoButton
        function LimpiartodoButtonPushed(app, event)
            app.clearBtns();
            app.matL = [];
            app.dataUsed = false;
            
            app.lblConsole.Text = 'Variables limpiadas!!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.GridLayout2);
            app.aButton = uibutton(app.GridLayout2_2, 'state');
            app.aButton.Text = 'a';
            app.bButton = uibutton(app.GridLayout2_2, 'state');
            app.bButton.Text = 'b';
            app.cButton = uibutton(app.GridLayout2_2, 'state');
            app.cButton.Text = 'c';
            app.dButton = uibutton(app.GridLayout2_2, 'state');
            app.dButton.Text = 'd';
            app.eButton = uibutton(app.GridLayout2_2, 'state');
            app.eButton.Text = 'e';
            app.fButton = uibutton(app.GridLayout2_2, 'state');
            app.fButton.Text = 'f';
            app.gButton = uibutton(app.GridLayout2_2, 'state');
            app.gButton.Text = 'g';
            app.hButton = uibutton(app.GridLayout2_2, 'state');
            app.hButton.Text = 'h';
            app.iButton = uibutton(app.GridLayout2_2, 'state');
            app.iButton.Text = 'i';
            app.jButton = uibutton(app.GridLayout2_2, 'state');
            app.jButton.Text = 'j';
            app.kButton = uibutton(app.GridLayout2_2, 'state');
            app.kButton.Text = 'k';
            app.lButton = uibutton(app.GridLayout2_2, 'state');
            app.lButton.Text = 'l';
            app.mButton = uibutton(app.GridLayout2_2, 'state');
            app.mButton.Text = 'm';
            app.nButton = uibutton(app.GridLayout2_2, 'state');
            app.nButton.Text = 'n';
            app.Button = uibutton(app.GridLayout2_2, 'state');
            app.Button.Text = 'ñ';
            app.oButton = uibutton(app.GridLayout2_2, 'state');
            app.oButton.Text = 'o';
            app.pButton = uibutton(app.GridLayout2_2, 'state');
            app.pButton.Text = 'p';
            app.qButton = uibutton(app.GridLayout2_2, 'state');
            app.qButton.Text = 'q';
            app.rButton = uibutton(app.GridLayout2_2, 'state');
            app.rButton.Text = 'r';
            app.sButton = uibutton(app.GridLayout2_2, 'state');
            app.sButton.Text = 's';
            app.tButton = uibutton(app.GridLayout2_2, 'state');
            app.tButton.Text = 't';
            app.uButton = uibutton(app.GridLayout2_2, 'state');
            app.uButton.Text = 'u';
            app.vButton = uibutton(app.GridLayout2_2, 'state');
            app.vButton.Text = 'v';
            app.wButton = uibutton(app.GridLayout2_2, 'state');
            app.wButton.Text = 'w';
            app.xButton = uibutton(app.GridLayout2_2, 'state');
            app.xButton.Text = 'x';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.AgregarButton = uibutton(app.GridLayout3, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.GenerarButton = uibutton(app.GridLayout3, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.LimpiarmatrizButton = uibutton(app.GridLayout3, 'push');
            app.LimpiarmatrizButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarmatrizButtonPushed, true);
            app.LimpiarmatrizButton.Text = 'Limpiar matriz';
            app.LimpiartodoButton = uibutton(app.GridLayout3, 'push');
            app.LimpiartodoButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiartodoButtonPushed, true);
            app.LimpiartodoButton.Text = 'Limpiar todo';
            app.lblConsole = uilabel(app.GridLayout3);
            app.lblConsole.Text = '';
            app.Ejemplo1Button = uibutton(app.GridLayout3, 'push');
            app.Ejemplo1Button.ButtonPushedFcn = createCallbackFcn(app, @Ejemplo1ButtonPushed, true);
            app.Ejemplo1Button.Text = 'Ejemplo 1';
            app.Ejemplo2Button = uibutton(app.GridLayout3, 'push');
            app.Ejemplo2Button.ButtonPushedFcn = createCallbackFcn(app, @Ejemplo2ButtonPushed, true);
            app.Ejemplo2Button.Text = 'Ejemplo 2';
            app.OSpinner = uispinner(app.GridLayout3);
            app.OSpinner.Limits = [1 Inf];
            app.OSpinner.Value = 1;
            app.UmbralLabel = uilabel(app.GridLayout3);
            app.UmbralLabel.Text = 'Umbral';
            app.GeneradordearboldepatronesfrecuentesLabel = uilabel(app.GridLayout);
            app.GeneradordearboldepatronesfrecuentesLabel.Text = 'Generador de arbol de patrones frecuentes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end